classdef backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        delayLabel_3                   matlab.ui.control.Label
        delay_switch_3                 matlab.ui.control.Switch
        fliped_3                       matlab.ui.control.Switch
        flippedaudioLabel_3            matlab.ui.control.Label
        adsrLabel                      matlab.ui.control.Label
        Switch_5                       matlab.ui.control.Switch
        releseSlider_3                 matlab.ui.control.Slider
        releseSlider_3Label            matlab.ui.control.Label
        sustainSlider_3                matlab.ui.control.Slider
        sustainSlider_3Label           matlab.ui.control.Label
        decaySlider_4                  matlab.ui.control.Slider
        decaySlider_4Label             matlab.ui.control.Label
        attackSlider_3                 matlab.ui.control.Slider
        attackSlider_3Label            matlab.ui.control.Label
        volumeSlider_3                 matlab.ui.control.Slider
        volumeSlider_3Label            matlab.ui.control.Label
        PitchKnob_3                    matlab.ui.control.Knob
        PitchKnob_3Label               matlab.ui.control.Label
        tempoKnob_3                    matlab.ui.control.Knob
        tempoKnob_3Label               matlab.ui.control.Label
        convolesoundsButton            matlab.ui.control.Button
        saveButton                     matlab.ui.control.Button
        stopButton_4                   matlab.ui.control.Button
        PlayButton_4                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Audio1Tab                      matlab.ui.container.Tab
        filenameEditField              matlab.ui.control.EditField
        filenameEditFieldLabel         matlab.ui.control.Label
        resetButton                    matlab.ui.control.Button
        stopButton                     matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        grainTab                       matlab.ui.container.Tab
        setgrainButton                 matlab.ui.control.Button
        playlistButton                 matlab.ui.control.Button
        clearButton                    matlab.ui.control.Button
        grainnameEditField             matlab.ui.control.EditField
        grainnameEditFieldLabel        matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        playtimeEditField              matlab.ui.control.NumericEditField
        playtimeEditFieldLabel         matlab.ui.control.Label
        savegrainButton                matlab.ui.control.Button
        lengthofgrainEditField         matlab.ui.control.NumericEditField
        lengthofgrainEditFieldLabel    matlab.ui.control.Label
        startofthegrainEditField       matlab.ui.control.NumericEditField
        startofthegrainEditFieldLabel  matlab.ui.control.Label
        playgrainButton                matlab.ui.control.Button
        adsrTab                        matlab.ui.container.Tab
        decaySlider_3                  matlab.ui.control.Slider
        decaySlider_3Label             matlab.ui.control.Label
        ADSRSwitch                     matlab.ui.control.Switch
        releseSlider                   matlab.ui.control.Slider
        releseSliderLabel              matlab.ui.control.Label
        sustainSlider                  matlab.ui.control.Slider
        sustainSliderLabel             matlab.ui.control.Label
        attackSlider                   matlab.ui.control.Slider
        attackSliderLabel              matlab.ui.control.Label
        changesTab                     matlab.ui.container.Tab
        Switch_3                       matlab.ui.control.Switch
        flippedaudioLabel              matlab.ui.control.Label
        PitchKnob_2Label               matlab.ui.control.Label
        tempoKnobLabel                 matlab.ui.control.Label
        PitchKnob_1                    matlab.ui.control.Knob
        tempoKnob_1                    matlab.ui.control.Knob
        volumeSlider                   matlab.ui.control.Slider
        volumeSliderLabel              matlab.ui.control.Label
        chordDropDown                  matlab.ui.control.DropDown
        chordDropDownLabel             matlab.ui.control.Label
        humremovalTab                  matlab.ui.container.Tab
        cutButton                      matlab.ui.control.Button
        selectButton                   matlab.ui.control.Button
        effectsTab                     matlab.ui.container.Tab
        delayLabel                     matlab.ui.control.Label
        wahwahLabel                    matlab.ui.control.Label
        wahwahswitch                   matlab.ui.control.Switch
        delay_switch                   matlab.ui.control.Switch
        UIAxes_1                       matlab.ui.control.UIAxes
        Audio2Tab                      matlab.ui.container.Tab
        resetButton_2                  matlab.ui.control.Button
        TabGroup2_2                    matlab.ui.container.TabGroup
        grainTab_2                     matlab.ui.container.Tab
        grainnameEditField_2           matlab.ui.control.EditField
        grainnameEditField_2Label      matlab.ui.control.Label
        playlistButton_2               matlab.ui.control.Button
        setgrainButton_2               matlab.ui.control.Button
        clearButton_2                  matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        playtimeEditField_2            matlab.ui.control.NumericEditField
        playtimeEditField_2Label       matlab.ui.control.Label
        savegrainButton_2              matlab.ui.control.Button
        lengthofgrainEditField_2       matlab.ui.control.NumericEditField
        lengthofgrainEditField_2Label  matlab.ui.control.Label
        startofthegrainEditField_2     matlab.ui.control.NumericEditField
        startofthegrainEditField_2Label  matlab.ui.control.Label
        playgrainButton_2              matlab.ui.control.Button
        adsrTab_2                      matlab.ui.container.Tab
        Switch_2                       matlab.ui.control.Switch
        releseSlider_2                 matlab.ui.control.Slider
        releseSlider_2Label            matlab.ui.control.Label
        sustainSlider_2                matlab.ui.control.Slider
        sustainSlider_2Label           matlab.ui.control.Label
        decaySlider_2                  matlab.ui.control.Slider
        decaySlider_2Label             matlab.ui.control.Label
        attackSlider_2                 matlab.ui.control.Slider
        attackSlider_2Label            matlab.ui.control.Label
        changesTab_2                   matlab.ui.container.Tab
        flippedaudioLabel_2            matlab.ui.control.Label
        Switch_4                       matlab.ui.control.Switch
        chordDropDown_2                matlab.ui.control.DropDown
        chordDropDown_2Label           matlab.ui.control.Label
        volumeSlider_2                 matlab.ui.control.Slider
        volumeSlider_2Label            matlab.ui.control.Label
        PitchKnob_2                    matlab.ui.control.Knob
        PitchKnobLabel                 matlab.ui.control.Label
        tempoKnob_2                    matlab.ui.control.Knob
        tempoKnob_2Label               matlab.ui.control.Label
        HUMREMVALTab_2                 matlab.ui.container.Tab
        selectButton_2                 matlab.ui.control.Button
        cutButton_2                    matlab.ui.control.Button
        effectsTab_2                   matlab.ui.container.Tab
        wahwahLabel_2                  matlab.ui.control.Label
        wahwahswitch_2                 matlab.ui.control.Switch
        delayLabel_2                   matlab.ui.control.Label
        delay_switch_2                 matlab.ui.control.Switch
        PlayButton_2                   matlab.ui.control.Button
        stopButton_2                   matlab.ui.control.Button
        LoadButton_2                   matlab.ui.control.Button
        filenameEditField_2Label       matlab.ui.control.Label
        filenameEditField_2            matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        y
        new_y
        y_2
        new_y2
        Fs
        Fs_2
        roi
        yconv 
        grainlist={}
        combined_audio
        t
        savedgrainlist
        hy
        hy_2
        grainlist_2 = {}
        combined_audio_2 
        t_2
        savedgrainlist_2
        new_yconv 
    end
 
    methods (Access = private)
        
        function y=synth(app,freq,dur,amp,Fs,type)
            % y=synth(freq,dur,amp,Fs,type)
            %
            % Synthesize a single note
            %
            % Inputs:
            %  freq - frequency in Hz
            %  dur - duration in seconds
            %  amp - Amplitude in range [0,1]
            %  Fs -  sampling frequency in Hz
            %  type - string to select synthesis type
            %         current options: 'fm', 'sine', or 'saw'
            %
            %---------------------------------------------------------------
            % Subversion Revision: 12 (2006-01-24)
            %
            % This software can be used freely for non-commerical use.
            % Visit http://www.kenschutte.com/software for more
            %   documentation, copyright info, and updates.
            %---------------------------------------------------------------
            
                if nargin<5
                  error('Five arguments required for synth()');
                end
                
                N = floor(dur*Fs);
                n=0:N-1;
                if (strcmp(type,'sine'))
                  y = amp.*sin(2*pi*n*freq/Fs);
                
                elseif (strcmp(type,'saw'))
                
                  T = (1/freq)*Fs;     % period in fractional samples
                  ramp = (0:(N-1))/T;
                  y = ramp-fix(ramp);
                  y = amp.*y;
                  y = y - mean(y);
                
                elseif (strcmp(type,'fm'))
                
                  t = 0:(1/Fs):dur;
                  envel = interp1([0 dur/6 dur/3 dur/5 dur], [0 1 .75 .6 0], 0:(1/Fs):dur);
                  I_env = 5.*envel;
                  y = envel.*sin(2.*pi.*freq.*t + I_env.*sin(2.*pi.*freq.*t));
                  
                else
                  error('Unknown synthesis type');
                end
                
                % smooth edges w/ 10ms ramp
                if (dur > .02)
                  L = 2*fix(.01*Fs)+1;  % L odd
                  ramp = bartlett(L)';  % odd length
                  L = ceil(L/2);
                  y(1:L) = y(1:L) .* ramp(1:L);
                  y(end-L+1:end) = y(end-L+1:end) .* ramp(end-L+1:end);
                end
        end
        
        function y = pvoc(app,x, r, n)
            % y = pvoc(x, r, n)  Time-scale a signal to r times faster with phase vocoder
            %      x is an input sound. n is the FFT size, defaults to 1024.  
            %      Calculate the 25%-overlapped STFT, squeeze it by a factor of r, 
            %      inverse spegram.
            % 2000-12-05, 2002-02-13 dpwe@ee.columbia.edu.  Uses pvsample, stft, istft
            % $Header: /home/empire6/dpwe/public_html/resources/matlab/pvoc/RCS/pvoc.m,v 1.3 2011/02/08 21:08:39 dpwe Exp $
            
            if nargin < 3
              n = 1024;
            end
            
            % With hann windowing on both input and output, 
            % we need 25% window overlap for smooth reconstruction
            hop = n/4;
            % Effect of hanns at both ends is a cumulated cos^2 window (for
            % r = 1 anyway); need to scale magnitudes by 2/3 for
            % identity input/output
            %scf = 2/3;
            % 2011-02-07: this factor is now included in istft.m
            scf = 1.0;
            
            % Calculate the basic STFT, magnitude scaled
            X = scf * stft(app,x', n, n, hop);
            
            % Calculate the new timebase samples
            [rows, cols] = size(X);
            t = 0:r:(cols-2);
            % Have to stay two cols off end because (a) counting from zero, and 
            % (b) need col n AND col n+1 to interpolate
            
            % Generate the new spectrogram
            X2 = pvsample(app,X, t, hop);
            
            % Invert to a waveform
            y = istft(app,X2, n, n, hop)';

        end
        
        function c = pvsample(app,b, t, hop)
            % c = pvsample(b, t, hop)   Interpolate an STFT array according to the 'phase vocoder'
            %     b is an STFT array, of the form generated by 'specgram'.
            %     t is a vector of (real) time-samples, which specifies a path through 
            %     the time-base defined by the columns of b.  For each value of t, 
            %     the spectral magnitudes in the columns of b are interpolated, and 
            %     the phase difference between the successive columns of b is 
            %     calculated; a new column is created in the output array c that 
            %     preserves this per-step phase advance in each bin.
            %     hop is the STFT hop size, defaults to N/2, where N is the FFT size
            %     and b has N/2+1 rows.  hop is needed to calculate the 'null' phase 
            %     advance expected in each bin.
            %     Note: t is defined relative to a zero origin, so 0.1 is 90% of 
            %     the first column of b, plus 10% of the second.
            % 2000-12-05 dpwe@ee.columbia.edu
            % $Header: /homes/dpwe/public_html/resources/matlab/dtw/../RCS/pvsample.m,v 1.3 2003/04/09 03:17:10 dpwe Exp $
            
            if nargin < 3
              hop = 0;
            end
            
            [rows,cols] = size(b);
            
            N = 2*(rows-1);
            
            if hop == 0
              % default value
              hop = N/2;
            end
            
            % Empty output array
            c = zeros(rows, length(t));
            
            % Expected phase advance in each bin
            dphi = zeros(1,N/2+1);
            dphi(2:(1 + N/2)) = (2*pi*hop)./(N./(1:(N/2)));
            
            % Phase accumulator
            % Preset to phase of first frame for perfect reconstruction
            % in case of 1:1 time scaling
            ph = angle(b(:,1));
            
            % Append a 'safety' column on to the end of b to avoid problems 
            % taking *exactly* the last frame (i.e. 1*b(:,cols)+0*b(:,cols+1))
            b = [b,zeros(rows,1)];
            
            ocol = 1;
            for tt = t
              % Grab the two columns of b
              bcols = b(:,floor(tt)+[1 2]);
              tf = tt - floor(tt);
              bmag = (1-tf)*abs(bcols(:,1)) + tf*(abs(bcols(:,2)));
              % calculate phase advance
              dp = angle(bcols(:,2)) - angle(bcols(:,1)) - dphi';
              % Reduce to -pi:pi range
              dp = dp - 2 * pi * round(dp/(2*pi));
              % Save the column
              c(:,ocol) = bmag .* exp(j*ph);
              % Cumulate phase, ready for next frame
              ph = ph + dphi' + dp;
              ocol = ocol+1;
            end
       end
        
        function D = stft(app,x, f, w, h, sr)
        % D = stft(X, F, W, H, SR)                       Short-time Fourier transform.
        %	Returns some frames of short-term Fourier transform of x.  Each 
        %	column of the result is one F-point fft (default 256); each
        %	successive frame is offset by H points (W/2) until X is exhausted.  
        %       Data is hann-windowed at W pts (F), or rectangular if W=0, or 
        %       with W if it is a vector.
        %       Without output arguments, will plot like sgram (SR will get
        %       axes right, defaults to 8000).
        %	See also 'istft.m'.
        % dpwe 1994may05.  Uses built-in 'fft'
        % $Header: /home/empire6/dpwe/public_html/resources/matlab/pvoc/RCS/stft.m,v 1.4 2010/08/13 16:03:14 dpwe Exp $
        
        if nargin < 2;  f = 256; end
        if nargin < 3;  w = f; end
        if nargin < 4;  h = 0; end
        if nargin < 5;  sr = 8000; end
        
        % expect x as a row
        if size(x,1) > 1
          x = x';
        end
        
        s = length(x);
        
        if length(w) == 1
          if w == 0
            % special case: rectangular window
            win = ones(1,f);
          else
            if rem(w, 2) == 0   % force window to be odd-len
              w = w + 1;
            end
            halflen = (w-1)/2;
            halff = f/2;   % midpoint of win
            halfwin = 0.5 * ( 1 + cos( pi * (0:halflen)/halflen));
            win = zeros(1, f);
            acthalflen = min(halff, halflen);
            win((halff+1):(halff+acthalflen)) = halfwin(1:acthalflen);
            win((halff+1):-1:(halff-acthalflen+2)) = halfwin(1:acthalflen);
          end
        else
          win = w;
        end
        
        w = length(win);
        % now can set default hop
        if h == 0
          h = floor(w/2);
        end
        
        c = 1;
        
        % pre-allocate output array
        d = zeros((1+f/2),1+fix((s-f)/h));
        
        for b = 0:h:(s-f)
          u = win.*x((b+1):(b+f));
          t = fft(u);
          d(:,c) = t(1:(1+f/2))';
          c = c+1;
        end;
        
        % If no output arguments, plot a spectrogram
        if nargout == 0
          tt = [0:size(d,2)]*h/sr;
          ff = [0:size(d,1)]*sr/f;
          imagesc(tt,ff,20*log10(abs(d)));
          axis('xy');
          xlabel('time / sec');
          ylabel('freq / Hz')
          % leave output variable D undefined
        else
          % Otherwise, no plot, but return STFT
          D = d;
        end

    
       end
        
        function x = istft(app,d, ftsize, w, h)
            % X = istft(D, F, W, H)                   Inverse short-time Fourier transform.
            %	Performs overlap-add resynthesis from the short-time Fourier transform 
            %	data in D.  Each column of D is taken as the result of an F-point 
            %	fft; each successive frame was offset by H points. Data is 
            %	hamm-windowed at W pts.  
            %       W = 0 gives a rectangular window; W as a vector uses that as window.
            % dpwe 1994may24.  Uses built-in 'ifft' etc.
            % $Header: /homes/dpwe/public_html/resources/matlab/pvoc/RCS/istft.m,v 1.4 2009/01/07 04:20:00 dpwe Exp $
            
            s = size(d);
            %if s(1) != (ftsize/2)+1
            %  error('number of rows should be fftsize/2+1')
            %end
             
            cols = s(2);
            xlen = ftsize + (cols-1)*h;
            x = zeros(1,xlen);
            
            if length(w) == 1
              if w == 0
                % special case: rectangular window
                win = ones(1,ftsize);
              else
                if rem(w, 2) == 0   % force window to be odd-len
                  w = w + 1;
                end
                halflen = (w-1)/2;
                halff = ftsize/2;
                halfwin = 0.5 * ( 1 + cos( pi * (0:halflen)/halflen));
                win = zeros(1, ftsize);
                acthalflen = min(halff, halflen);
                win((halff+1):(halff+acthalflen)) = halfwin(1:acthalflen);
                win((halff+1):-1:(halff-acthalflen+2)) = halfwin(1:acthalflen);
                % 2009-01-06: Make stft-istft loop be identity
                win = 2/3*win;
              end
            else
              win = w;
              w = length(win);
            end
               
            for b = 0:h:(h*(cols-1))
              ft = d(:,1+b/h)';
              ft = [ft, conj(ft([((ftsize/2)):-1:2]))];
              px = real(ifft(ft));
              x((b+1):(b+ftsize)) = x((b+1):(b+ftsize))+px.*win;
            end

        end
        
        function axis_display(app,y,axis)

            n = 1000;
            nhop = n/4;
            Y = stft(app,y,n,n,nhop);
            specy = abs(Y)/n;


            if axis == 1
            I = imshow((flipud(255*specy)), 'Parent', app.UIAxes_1,...
                'XData', [1 app.UIAxes_1.Position(3)], ...
                'YData', [1 app.UIAxes_1.Position(4)]);

            % Display image and stretch to fill axes
            app.UIAxes_1.XLim = [0 I.XData(2)];
            app.UIAxes_1.YLim = [0 I.YData(2)];
            colormap(app.UIAxes_1, hsv);
           
            elseif axis == 2
            I = imshow((flipud(255*specy)), 'Parent', app.UIAxes_2,...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);

            % Display image and stretch to fill axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            colormap(app.UIAxes_2, hsv);

            elseif axis == 3
            I = imshow((flipud(255*specy)), 'Parent', app.UIAxes_3,...
                'XData', [1 app.UIAxes_3.Position(3)], ...
                'YData', [1 app.UIAxes_3.Position(4)]);

            % Display image and stretch to fill axes
            app.UIAxes_3.XLim = [0 I.XData(2)];
            app.UIAxes_3.YLim = [0 I.YData(2)];
            colormap(app.UIAxes_3, hsv);   
            

            end
        end

        
        function y = grainLn(app,x,iniz,L,Lw)
        % extract a long grain
        % x    input signal
        % init first sample
        % L    grain length (in samples)
        % Lw   length fade-in and fade-out (in samples)

            
            y = x(iniz:iniz+L-1);                      % extract segment
            w = hanning(2*Lw+1);
            
            w = w(:); y = y(:);
            
            y(1:Lw)     = y(1:Lw).*w(1:Lw);            % fade-in
            y(L-Lw+1:L) = y(L-Lw+1:L).*w(Lw+2:2*Lw+1); % fade-out
        end
        
        function [y]=fconv(app,x, h)
            %FCONV Fast Convolution
            %   [y] = FCONV(x, h) convolves x and h, and normalizes the output  
            %         to +-1.
            %
            %      x = input vector
            %      h = input vector
            % 
            %      See also CONV
            %
            %   NOTES:
            %
            %   1) I have a short article explaining what a convolution is.  It
            %      is available at http://stevem.us/fconv.html.
            %
            %
            %Version 1.0
            %Coded by: Stephen G. McGovern, 2003-2004.
            
            Ly=length(x)+length(h)-1;  % 
            Ly2=pow2(nextpow2(Ly));    % Find smallest power of 2 that is > Ly
            X=fft(x, Ly2);		   % Fast Fourier transform
            H=fft(h, Ly2);	           % Fast Fourier transform
            Y=X.*H;        	           % 
            y=real(ifft(Y, Ly2));      % Inverse fast Fourier transform
            y=y(1:1:Ly);               % Take just the first N elements
            y=y/max(abs(y));           % Normalize
                        
        end
        
        function [grain] = grain(app,n)
   
            if n ==1
                if app.lengthofgrainEditField.Value ==0

                    errordlg('select a length ,grain error');
                    grain = app.y;

                else 
                    Lw=round(app.Fs*0.01);
                    L = app.Fs*(app.lengthofgrainEditField.Value/1000);   %grain length
                    initIn = app.startofthegrainEditField.Value* app.Fs;      %init graind
                    grain =grainLn(app,app.y,initIn,L,Lw);
                    j =(app.playtimeEditField.Value/(app.lengthofgrainEditField.Value/1000));
                    grain = repmat(grain,j,1);
                end
            end

            if n ==2
                if app.lengthofgrainEditField_2.Value ==0

                    errordlg('select a length ,grain error');
                    grain = app.y_2;

                else
       
                    Lw=round(app.Fs_2*0.01);
                    L = app.Fs_2*(app.lengthofgrainEditField_2.Value/1000);   %grain length
                    initIn = app.startofthegrainEditField_2.Value* app.Fs_2;      %init graind
                    grain =grainLn(app,app.y_2,initIn,L,Lw);
                    j =(app.playtimeEditField_2.Value/(app.lengthofgrainEditField_2.Value/1000));
                    grain = repmat(grain,j,1);
                end

            end

        end
        function envAudio = adsrEnv(app,attackTime, decayTime, sustainLevel, releaseTime, audio)

                % Define the total duration of the envelope (in seconds)
                totalTime =length(audio);
                
                attackTime = (attackTime/100) *totalTime;
                decayTime = attackTime-((decayTime/100) *totalTime);
                sustainLevel = (sustainLevel/100) *totalTime;
                releaseTime = decayTime-((releaseTime/100) *totalTime);
                
                % Define the time points at which the envelope should be evaluated
                time = linspace(0, totalTime, 1000);
                
                % Compute the envelope values at each time point
                env = zeros(size(time));
                
                % Attack phase (linearly increasing from 0 to 1)
                idx = time < attackTime;
                env(idx) = interp1([0 attackTime], [0 1], time(idx));
                
                % Decay phase (linearly decreasing from 1 to sustainLevel)
                idx = time >= attackTime & time < attackTime+decayTime;
                env(idx) = interp1([attackTime attackTime+decayTime], [1 sustainLevel], time(idx));
                
                % Sustain phase (constant at sustainLevel)
                idx = time >= attackTime+decayTime;
                env(idx) = sustainLevel;
                
                % Release phase (linearly decreasing from sustainLevel to 0)
                idx = time >= attackTime+decayTime+releaseTime;
                env(idx) = interp1([attackTime+decayTime+releaseTime totalTime], [sustainLevel 0], time(idx));
                
                % Apply the envelope to the audio signal
                envAudio = audio .* env;
        end
      
        
        function output = delay_effect(app,signal, fs)% Load the audio signal
       
            
            % Set the delay time in seconds
            delay_time = 0.2;
            
            % Calculate the delay in samples
            delay_samples = round(delay_time * fs);
            
            % Create the delay line
            delay_line = zeros(delay_samples, 1);
            
            % Initialize the output signal
            output = zeros(size(signal));
            
            % Loop through the samples of the signal
            for n = 1:length(signal)
              % Read the current sample from the delay line
              delayed_sample = delay_line(1);
              
              % Write the current sample to the delay line
              delay_line(2:end) = delay_line(1:end-1);
              delay_line(1) = signal(n);
              
              % Mix the current sample with the delayed sample
              output(n) = signal(n) + delayed_sample;
            end

        end


        function results = humremoval(app,y)

            if  isempty(app.roi)
                errordlg('no selection','selection error');
            else 
                xmin=       app.roi.Position(1);
                ymin =      app.roi.Position(2);
                width =     app.roi.Position(3);
                height =    app.roi.Position(4);
                n = 1024;
                nhop = n/4;
                Yedit = stft(app,y,n,n,nhop);

                Yedit(ymin :height+ymin , xmin : xmin+width) = 0;

                results = istft(app,Yedit,n,n,nhop);

            end
            
        end
        
        function out = flipaud(app,in)
          % Flip the input matrix along the vertical (up-down) axis
          out = in(end:-1:1, :);
        end
        
        
        function list_of_grains(app,grain)
        
            % Add the audio snippet to the list
            app.grainlist = cat(1, grain, app.grainlist);
            app.combined_audio = vertcat(app.grainlist{:});
            name= app.grainnameEditField.Value;
            nr = {name};
            app.UITable.Data= [app.t;nr];
            app.t = app.UITable.Data;


        end
          function list_of_grains_2(app,grain)
        
            % Add the audio snippet to the list
            app.grainlist_2 = cat(1, grain, app.grainlist_2);
            app.combined_audio_2 = vertcat(app.grainlist_2{:});
            name= app.grainnameEditField_2.Value;
            nr = {name};
            app.UITable_2.Data = [app.t_2;nr];
            app.t_2 = app.UITable_2.Data;


        end

        
        function yWah = wahwah(app,y,fs)
          % Set the center frequency range for the wah wah effect
          f0min = 50;  % Minimum center frequency (Hz)
          f0max = 200; % Maximum center frequency (Hz)
        
          % Set the sweep rate for the wah wah effect
          sweepRate = 5;  % Hz/s
        
          % Set the length of the audio signal (in seconds)
          time = (0:length(y)-1)/fs;
        
          % Generate a time-varying center frequency for the wah wah effect
          f0 = f0min + (f0max - f0min)*(1 + cos(2*pi*sweepRate*time))/2;
        
          % Generate a band-pass filter with the time-varying center frequency
          Q = 30;  % Quality factor
          b = f0./Q;
          a = [1, b./f0, 1];
        
          % Apply the wah wah effect to the audio signal
          yWah = filter(b, a, y);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged(app, event)
            value = app.filenameEditField.Value;
            [filename,path] = uigetfile();
            app.filenameEditField.Value = filename;
            [app.y,app.Fs] = audioread(filename);
            figure(app.UIFigure)
            app.axis_1_display(app.y)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           
            [filename,path] = uigetfile();

            app.filenameEditField.Value = filename;
            [app.y,app.Fs] = audioread(filename);
            app.new_y = app.y;
            app.hy= app.y;
            figure(app.UIFigure)

            [~,channel_no] = size(app.y);
            if (channel_no > 1)
            app.y = mean(app.y,2)';
            end
            app.axis_display(app.y,1)
            

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
                 % show a message if there is no file loaded
            if isempty(app.y)
                errordlg('No File Loaded','File Error');
                
            else

            %takes values for pitch, tempo and vloue and applies them
            %before playing sound 

            %tempo
            if isempty(app.combined_audio)
                j = app.hy;

            else
                j = app.combined_audio;
            end
           
            app.new_y =pvoc(app,j,app.tempoKnob_1.Value,1024);
            %pith
            [N,D] = rat(app.PitchKnob_1.Value);
            ypvoc =pvoc(app,app.new_y, app.PitchKnob_1.Value,1024); 
            app.new_y = resample(ypvoc,N,D); 
            %volume
            app.new_y= app.volumeSlider.Value* app.new_y ;
            

            if strcmp(app.Switch_3.Value,'On')
                app.new_y= flipaud(app,app.new_y);
            end

            
            if strcmp(app.delay_switch.Value,'On')
                app.new_y = app.delay_effect(app.new_y,app.Fs);
            end

            if strcmp(app.wahwahswitch.Value,'On')
                app.new_y = app.wahwah(app.new_y,app.Fs);
            end
          
            %play and display the new edited sound 
            app.axis_display(app.new_y,1)
            sound(app.new_y,app.Fs)
            end

        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % show a message if there is no file loaded 
            if isempty(app.y)
                errordlg('No File Loaded','File Error');
            else
             %stops the sound being played
                clear sound
            end

        end

        % Value changed function: volumeSlider
        function volumeSliderValueChanged(app, event)
            value = app.volumeSlider.Value;
            
        end

        % Value changed function: filenameEditField_2
        function filenameEditField_2ValueChanged(app, event)
            value = app.filenameEditField_2.Value;
           
            [filename,path] = uigetfile();
            app.filenameEditField_2.Value = filename;
            [app.y_2,app.Fs_2] = audioread(filename);
            figure(app.UIFigure)
            app.axis_2_display(app.y_2)


        end

        % Value changed function: startofthegrainEditField
        function startofthegrainEditFieldValueChanged(app, event)
            value = app.startofthegrainEditField.Value;
            
        end

        % Value changed function: lengthofgrainEditField
        function lengthofgrainEditFieldValueChanged(app, event)
            value = app.lengthofgrainEditField.Value;
            
        end

        % Value changed function: playtimeEditField
        function playtimeEditFieldValueChanged(app, event)
            value = app.playtimeEditField.Value;
            
        end

        % Value changed function: tempoKnob_1
        function tempoKnob_1ValueChanged(app, event)
            value = app.tempoKnob_1.Value;
            
        end

        % Button pushed function: savegrainButton
        function savegrainButtonPushed(app, event)
                if isempty(app.new_y)
                errordlg('No File Loaded','File Error');app.savedgrainlist_2 = app.combined_audio_2;
                else
            
                g = grain(app,1);
                list_of_grains(app,g)
                end

        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            %loads the file directorty chosen and displays the audio file
            %on the corisponding axis 


            [filename,path] = uigetfile();

            app.filenameEditField_2.Value = filename;
            [app.y_2,app.Fs_2] = audioread(filename);
            app.new_y2 = app.y_2;
            app.hy_2= app.y_2;
            figure(app.UIFigure)

            [~,channel_no] = size(app.y_2);
            if (channel_no > 1)
            app.y = mean(app.y_2,2)';
            end

            app.axis_display(app.y_2,2)
            

        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
           

               % show a message if there is no file loaded
            if isempty(app.y_2)
                errordlg('No File Loaded','File Error');
                
            else

            %takes values for pitch, tempo and vloue and applies them
            %before playing sound 

            %tempo
            if isempty(app.combined_audio_2)
                j2 = app.hy_2;

            else
                j2 = app.combined_audio_2;
            end
            
            
            app.new_y2 =pvoc(app,j2,app.tempoKnob_2.Value,1024);
           
            %pith
            [N,D] = rat(app.PitchKnob_2.Value);
            ypvoc =pvoc(app,app.new_y2, app.PitchKnob_2.Value,1024); 
            app.new_y2 = resample(ypvoc,N,D); 
            %volume
            app.new_y2= app.volumeSlider_2.Value* app.new_y2 ;


            if strcmp(app.Switch_4.Value,'On')
                app.new_y2= flipaud(app,app.new_y2);
            end

            
            if strcmp(app.delay_switch_2.Value,'On')
                app.new_y2 = app.delay_effect(app.new_y2,app.Fs_2);
            end

            if strcmp(app.wahwahswitch_2.Value,'On')
                app.new_y2 = app.wahwah(app.new_y2,app.Fs_2);
            end
            

            %play and display the new edited sound 
            app.axis_display(app.new_y2,2)
            sound(app.new_y2,app.Fs_2)
            end


        end

        % Value changed function: tempoKnob_2
        function tempoKnob_2ValueChanged(app, event)
            value = app.tempoKnob_2.Value;
            
        end

        % Button pushed function: stopButton_2
        function stopButton_2Pushed(app, event)
            % show a message if there is no file loaded  
            if isempty(app.y_2)
                errordlg('No File Loaded','File Error');
            else
            %stops the sound being played 
                clear sound
            end

        end

        % Value changed function: PitchKnob_2
        function PitchKnob_2ValueChanged(app, event)
            value = app.PitchKnob_2.Value;
            
        end

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            %allows the user to celect a rectangulare area using
            %drawrectrangle 
            app.roi = drawrectangle(app.UIAxes_1,'color','b');
        end

        % Button pushed function: cutButton
        function cutButtonPushed(app, event)

            app.new_y = humremoval(app,app.new_y);
            axis_display(app,app.new_y,1)

       
        end

        % Button pushed function: selectButton_2
        function selectButton_2Pushed(app, event)
            %allows the user to celect a rectangulare area using
            %drawrectrangle 
            app.roi = drawrectangle(app.UIAxes_2,'color','b');
        end

        % Button pushed function: cutButton_2
        function cutButton_2Pushed(app, event)
            
            
            xmin=       app.roi.Position(1);
            ymin =      app.roi.Position(2);
            width =     app.roi.Position(3);
            height =    app.roi.Position(4);
            
            n = 1024;
            nhop = n/4;
            Yedit = stft(app,app.y_2,n,n,nhop);
            
            Yedit(ymin :  height+ymin , xmin : xmin+width) = 0;
          
            app.new_y2 = istft(app,Yedit,n,n,nhop);
         
    
            axis_display(app,app.new_y2,2)


        end

        % Button pushed function: convolesoundsButton
        function convolesoundsButtonPushed(app, event)
            app.yconv = fconv(app,app.new_y,app.new_y2);
            app.new_yconv = app.yconv;
            app.axis_display(app.yconv,3)
        end

        % Button pushed function: PlayButton_4
        function PlayButton_4Pushed(app, event)
                  % show a message if there is no file loaded
            if isempty(app.yconv)
                errordlg('No sound convolved','File Error');
                
            else

            %takes values for pitch, tempo and vloue and applies them
            %before playing sound 

            %tempo
            
           
            app.new_yconv =pvoc(app,app.yconv,app.tempoKnob_3.Value,1024);
            %pith
            [N,D] = rat(app.PitchKnob_1.Value);
            ypvoc =pvoc(app,app.new_yconv, app.PitchKnob_3.Value,1024); 
            app.new_yconv = resample(ypvoc,N,D); 
            %volume
            app.new_yconv= app.volumeSlider_3.Value* app.new_yconv ;
            if strcmp(app.Switch_5.Value,'On')
                app.new_yconv = adsrEnv(app, ...
                    app.attackSlider_3.Value, ...
                    app.decaySlider_4.Value, ...
                    app.sustainSlider_3.Value, ...
                    app.releseSlider_3.Value, ...
                    app.new_yconv);
            end
            if strcmp(app.fliped_3.Value,'On')
                    app.new_yconv= flipaud(app,app.new_yconv); 
            
            end
            if strcmp(app.delay_switch_3.Value,'On')
                app.new_yconv = delay_effect(app,app.new_yconv);
            %play and display the new edited sound 
            end
             app.axis_display(app.new_yconv,3)
             sound(app.new_yconv,app.Fs)
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.new_y= [];
            app.hy= [];
            app.axis_display(app.new_y,1)
            app.y=[];
            app.Fs=[];
            app.grainlist={};
            app.combined_audio=[];
            app.t=[];
            app.savedgrainlist=[];
        end

        % Value changed function: PitchKnob_1
        function PitchKnob_1ValueChanged(app, event)
            value = app.PitchKnob_1.Value;
            
        end

        % Value changed function: attackSlider
        function attackSliderValueChanged(app, event)
            value = app.attackSlider.Value;
            
        end

        % Value changed function: sustainSlider
        function sustainSliderValueChanged(app, event)
            value = app.sustainSlider.Value;
        end

        % Value changed function: releseSlider
        function releseSliderValueChanged(app, event)
            value = app.releseSlider.Value;

            min = app.decaySlider_3.Value ;
            app.releseSlider.Limits = [min 100];
            
        end

        % Button pushed function: playgrainButton
        function playgrainButtonPushed(app, event)
            
            if isempty(app.new_y)
                errordlg('No File Loaded','File Error');
            else

            g= grain(app,1);
            sound(g,app.Fs)
            end
        end

        % Value changed function: decaySlider_3
        function decaySlider_3ValueChanged(app, event)
            value = app.decaySlider_3.Value;
            min = app.attackSlider.Value ;
            app.decaySlider_3.Limits = [min 100];
        end

        % Button pushed function: playgrainButton_2
        function playgrainButton_2Pushed(app, event)
              
            if isempty(app.new_y2)
                errordlg('No File Loaded','File Error');
            else
            g = grain(app,2);
            sound(g,app.Fs_2)
            end

        end

        % Value changed function: attackSlider_2
        function attackSlider_2ValueChanged(app, event)
            value = app.attackSlider_2.Value;
            
        end

        % Value changed function: decaySlider_2
        function decaySlider_2ValueChanged(app, event)
           value = app.decaySlider_2.Value;
            min = app.attackSlider_2.Value ;
            app.decaySlider_2.Limits = [min 100];
        end

        % Value changed function: sustainSlider_2
        function sustainSlider_2ValueChanged(app, event)
            value = app.sustainSlider_2.Value;
            
        end

        % Value changed function: releseSlider_2
        function releseSlider_2ValueChanged(app, event)
            value = app.releseSlider_2.Value;
            min = app.decaySlider_2.Value ;
            app.releseSlider_2.Limits = [min 100];
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            
        end

        % Value changed function: delay_switch
        function delay_switchValueChanged(app, event)
            value = app.delay_switch.Value;
            
        end

        % Value changed function: chordDropDown
        function chordDropDownValueChanged(app, event)
            value = app.chordDropDown.Value;

            if strcmp(app.chordDropDown.Value,'None')
            end
            if strcmp(app.chordDropDown.Value,'F3')
            end
            if strcmp(app.chordDropDown.Value,'G3')
            end
            if strcmp(app.chordDropDown.Value,'A3')
            end
            if strcmp(app.chordDropDown.Value,'B3')
            end
            if strcmp(app.chordDropDown.Value,'C4')
            end
            if strcmp(app.chordDropDown.Value,'D4')
            end
            if strcmp(app.chordDropDown.Value,'E4')
            end
            if strcmp(app.chordDropDown.Value,'F4')
            end
            if strcmp(app.chordDropDown.Value,'G4')
            end
            if strcmp(app.chordDropDown.Value,'A4')
            end
            if strcmp(app.chordDropDown.Value,'B4')
            end
            if strcmp(app.chordDropDown.Value,'C4')
            end
            if strcmp(app.chordDropDown.Value,'D5')
            end
            if strcmp(app.chordDropDown.Value,'D5')
            end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: grainnameEditField
        function grainnameEditFieldValueChanged(app, event)
            value = app.grainnameEditField.Value;
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.UITable.Data= {};
            app.combined_audio = [];
            app.t =[];
            app.savedgrainlist=[];
            app.grainlist = {};
            
        end

        % Button pushed function: playlistButton
        function playlistButtonPushed(app, event)
            if isempty(app.combined_audio)
                errordlg('No File Loaded','File Error');
                else
            sound(app.combined_audio,app.Fs)
            end
        end

        % Value changed function: wahwahswitch
        function wahwahswitchValueChanged(app, event)
            value = app.wahwahswitch.Value;
            
        end

        % Button pushed function: setgrainButton
        function setgrainButtonPushed(app, event)
            if isempty(app.combined_audio)
                errordlg('No File Loaded','File Error');
                else
            app.savedgrainlist = app.combined_audio;
            end
        end

        % Button pushed function: setgrainButton_2
        function setgrainButton_2Pushed(app, event)
              if isempty(app.combined_audio_2)
                errordlg('no grains set','File Error');
               
              else
                app.savedgrainlist_2 = app.combined_audio_2;
              end
        end

        % Button pushed function: playlistButton_2
        function playlistButton_2Pushed(app, event)
                 if isempty(app.combined_audio_2)
                errordlg('No grains in list','Grain Error');
                else
            
                sound(app.combined_audio_2,app.Fs_2)
                end
        end

        % Button pushed function: savegrainButton_2
        function savegrainButton_2Pushed(app, event)
                
                if isempty(app.new_y2)
                errordlg('No File Loaded','File Error');
                else
                g = grain(app,2);
                list_of_grains_2(app,g)
                end


        end

        % Value changed function: grainnameEditField_2
        function grainnameEditField_2ValueChanged(app, event)
            value = app.grainnameEditField_2.Value;
            
        end

        % Value changed function: wahwahswitch_2
        function wahwahswitch_2ValueChanged(app, event)
            value = app.wahwahswitch_2.Value;
            
        end

        % Value changed function: delay_switch_2
        function delay_switch_2ValueChanged(app, event)
            value = app.delay_switch_2.Value;
            
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            
        end

        % Button pushed function: resetButton_2
        function resetButton_2Pushed(app, event)
            app.new_y2= [];
            app.hy_2= [];
            app.axis_display(app.new_y2,2)
            app.y_2=[];
            app.Fs_2=[];
            app.grainlis_2={};
            app.combined_audio_2=[];
            app.t_2=[];
            app.savedgrainlist_2=[];
        end

        % Value changed function: ADSRSwitch
        function ADSRSwitchValueChanged(app, event)
            value = app.ADSRSwitch.Value;
            
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            
        end

        % Value changed function: attackSlider_3
        function attackSlider_3ValueChanged(app, event)
            value = app.attackSlider_3.Value;
            
        end

        % Value changed function: decaySlider_4
        function decaySlider_4ValueChanged(app, event)
            value = app.decaySlider_4.Value;
            
            min = app.attackSlider_3.Value ;
            app.decaySlider_4.Limits = [min 100];
            

        end

        % Value changed function: sustainSlider_3
        function sustainSlider_3ValueChanged(app, event)
            value = app.sustainSlider_3.Value;
            
        end

        % Value changed function: releseSlider_3
        function releseSlider_3ValueChanged(app, event)
            value = app.releseSlider_3.Value;
            
            min = app.decaySlider_4.Value ;
            app.releseSlider_3.Limits = [min 100];
            

        end

        % Value changed function: fliped_3
        function fliped_3ValueChanged(app, event)
            value = app.fliped_3.Value;
            
        end

        % Value changed function: delay_switch_3
        function delay_switch_3ValueChanged(app, event)
            value = app.delay_switch_3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Audio1Tab = uitab(app.TabGroup);
            app.Audio1Tab.Title = 'Audio 1';
            app.UIAxes_1 = uiaxes(app.Audio1Tab);
            title(app.UIAxes_1, 'SOURCE 1')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.TabGroup2 = uitabgroup(app.Audio1Tab);
            app.grainTab = uitab(app.TabGroup2);
            app.grainTab.Title = 'grain ';
            app.playgrainButton = uibutton(app.grainTab, 'push');
            app.playgrainButton.ButtonPushedFcn = createCallbackFcn(app, @playgrainButtonPushed, true);
            app.playgrainButton.Text = 'play grain';
            app.startofthegrainEditFieldLabel = uilabel(app.grainTab);
            app.startofthegrainEditFieldLabel.Text = 'start of the grain ';
            app.startofthegrainEditField = uieditfield(app.grainTab, 'numeric');
            app.startofthegrainEditField.ValueChangedFcn = createCallbackFcn(app, @startofthegrainEditFieldValueChanged, true);
            app.lengthofgrainEditFieldLabel = uilabel(app.grainTab);
            app.lengthofgrainEditFieldLabel.Text = 'length of grain';
            app.lengthofgrainEditField = uieditfield(app.grainTab, 'numeric');
            app.lengthofgrainEditField.ValueChangedFcn = createCallbackFcn(app, @lengthofgrainEditFieldValueChanged, true);
            app.savegrainButton = uibutton(app.grainTab, 'push');
            app.savegrainButton.ButtonPushedFcn = createCallbackFcn(app, @savegrainButtonPushed, true);
            app.savegrainButton.Text = 'save grain ';
            app.playtimeEditFieldLabel = uilabel(app.grainTab);
            app.playtimeEditFieldLabel.Text = 'playtime';
            app.playtimeEditField = uieditfield(app.grainTab, 'numeric');
            app.playtimeEditField.ValueChangedFcn = createCallbackFcn(app, @playtimeEditFieldValueChanged, true);
            app.UITable = uitable(app.grainTab);
            app.UITable.ColumnName = {'grain name'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.grainnameEditFieldLabel = uilabel(app.grainTab);
            app.grainnameEditFieldLabel.Text = 'grain name ';
            app.grainnameEditField = uieditfield(app.grainTab, 'text');
            app.grainnameEditField.ValueChangedFcn = createCallbackFcn(app, @grainnameEditFieldValueChanged, true);
            app.clearButton = uibutton(app.grainTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.playlistButton = uibutton(app.grainTab, 'push');
            app.playlistButton.ButtonPushedFcn = createCallbackFcn(app, @playlistButtonPushed, true);
            app.playlistButton.Text = 'play list ';
            app.setgrainButton = uibutton(app.grainTab, 'push');
            app.setgrainButton.ButtonPushedFcn = createCallbackFcn(app, @setgrainButtonPushed, true);
            app.setgrainButton.Text = 'set grain';
            app.adsrTab = uitab(app.TabGroup2);
            app.adsrTab.Title = 'adsr';
            app.attackSliderLabel = uilabel(app.adsrTab);
            app.attackSliderLabel.Text = 'attack ';
            app.attackSlider = uislider(app.adsrTab);
            app.attackSlider.ValueChangedFcn = createCallbackFcn(app, @attackSliderValueChanged, true);
            app.sustainSliderLabel = uilabel(app.adsrTab);
            app.sustainSliderLabel.Text = 'sustain';
            app.sustainSlider = uislider(app.adsrTab);
            app.sustainSlider.ValueChangedFcn = createCallbackFcn(app, @sustainSliderValueChanged, true);
            app.releseSliderLabel = uilabel(app.adsrTab);
            app.releseSliderLabel.Text = 'relese ';
            app.releseSlider = uislider(app.adsrTab);
            app.releseSlider.ValueChangedFcn = createCallbackFcn(app, @releseSliderValueChanged, true);
            app.ADSRSwitch = uiswitch(app.adsrTab, 'slider');
            app.ADSRSwitch.ValueChangedFcn = createCallbackFcn(app, @ADSRSwitchValueChanged, true);
            app.decaySlider_3Label = uilabel(app.adsrTab);
            app.decaySlider_3Label.Text = 'decay';
            app.decaySlider_3 = uislider(app.adsrTab);
            app.decaySlider_3.ValueChangedFcn = createCallbackFcn(app, @decaySlider_3ValueChanged, true);
            app.changesTab = uitab(app.TabGroup2);
            app.changesTab.Title = 'changes';
            app.chordDropDownLabel = uilabel(app.changesTab);
            app.chordDropDownLabel.Text = 'chord';
            app.chordDropDown = uidropdown(app.changesTab);
            app.chordDropDown.Items = {'None', 'F3', 'G3', 'A3', 'B3', 'C4', 'D4', 'C4', 'D4', 'E4', 'F4', 'G4', 'A4', 'B4', 'C5', 'D5'};
            app.chordDropDown.ValueChangedFcn = createCallbackFcn(app, @chordDropDownValueChanged, true);
            app.chordDropDown.Value = 'None';
            app.volumeSliderLabel = uilabel(app.changesTab);
            app.volumeSliderLabel.Text = 'volume';
            app.volumeSlider = uislider(app.changesTab);
            app.volumeSlider.Limits = [0 2];
            app.volumeSlider.ValueChangedFcn = createCallbackFcn(app, @volumeSliderValueChanged, true);
            app.volumeSlider.Value = 1;
            app.tempoKnob_1 = uiknob(app.changesTab, 'continuous');
            app.tempoKnob_1.Limits = [0 2];
            app.tempoKnob_1.MajorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_1.MajorTickLabels = {'0', '0.29', '0.58', '0.87', '1.16', '1.45', '1.74', '2'};
            app.tempoKnob_1.ValueChangedFcn = createCallbackFcn(app, @tempoKnob_1ValueChanged, true);
            app.tempoKnob_1.MinorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_1.Value = 1;
            app.PitchKnob_1 = uiknob(app.changesTab, 'continuous');
            app.PitchKnob_1.Limits = [0.1 2];
            app.PitchKnob_1.ValueChangedFcn = createCallbackFcn(app, @PitchKnob_1ValueChanged, true);
            app.PitchKnob_1.Value = 1.05;
            app.tempoKnobLabel = uilabel(app.changesTab);
            app.tempoKnobLabel.Text = 'tempo';
            app.PitchKnob_2Label = uilabel(app.changesTab);
            app.PitchKnob_2Label.Text = 'Pitch';
            app.flippedaudioLabel = uilabel(app.changesTab);
            app.flippedaudioLabel.Text = 'flipped audio ';
            app.Switch_3 = uiswitch(app.changesTab, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.humremovalTab = uitab(app.TabGroup2);
            app.humremovalTab.Title = 'humremoval';
            app.selectButton = uibutton(app.humremovalTab, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.cutButton = uibutton(app.humremovalTab, 'push');
            app.cutButton.ButtonPushedFcn = createCallbackFcn(app, @cutButtonPushed, true);
            app.cutButton.Text = 'cut ';
            app.effectsTab = uitab(app.TabGroup2);
            app.effectsTab.Title = 'effects';
            app.delay_switch = uiswitch(app.effectsTab, 'slider');
            app.delay_switch.ValueChangedFcn = createCallbackFcn(app, @delay_switchValueChanged, true);
            app.wahwahswitch = uiswitch(app.effectsTab, 'slider');
            app.wahwahswitch.ValueChangedFcn = createCallbackFcn(app, @wahwahswitchValueChanged, true);
            app.wahwahLabel = uilabel(app.effectsTab);
            app.wahwahLabel.Text = 'wah wah ';
            app.delayLabel = uilabel(app.effectsTab);
            app.delayLabel.Text = 'delay';
            app.LoadButton = uibutton(app.Audio1Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.Audio1Tab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.stopButton = uibutton(app.Audio1Tab, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.resetButton = uibutton(app.Audio1Tab, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.filenameEditFieldLabel = uilabel(app.Audio1Tab);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.Audio1Tab, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged, true);
            app.Audio2Tab = uitab(app.TabGroup);
            app.Audio2Tab.Title = 'Audio 2 ';
            app.UIAxes_2 = uiaxes(app.Audio2Tab);
            title(app.UIAxes_2, 'SOURCE 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.filenameEditField_2 = uieditfield(app.Audio2Tab, 'text');
            app.filenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @filenameEditField_2ValueChanged, true);
            app.filenameEditField_2Label = uilabel(app.Audio2Tab);
            app.filenameEditField_2Label.Text = 'file name';
            app.LoadButton_2 = uibutton(app.Audio2Tab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.stopButton_2 = uibutton(app.Audio2Tab, 'push');
            app.stopButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopButton_2Pushed, true);
            app.stopButton_2.Text = 'stop';
            app.PlayButton_2 = uibutton(app.Audio2Tab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.TabGroup2_2 = uitabgroup(app.Audio2Tab);
            app.grainTab_2 = uitab(app.TabGroup2_2);
            app.grainTab_2.Title = 'grain ';
            app.playgrainButton_2 = uibutton(app.grainTab_2, 'push');
            app.playgrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @playgrainButton_2Pushed, true);
            app.playgrainButton_2.Text = 'play grain';
            app.startofthegrainEditField_2Label = uilabel(app.grainTab_2);
            app.startofthegrainEditField_2Label.Text = 'start of the grain ';
            app.startofthegrainEditField_2 = uieditfield(app.grainTab_2, 'numeric');
            app.lengthofgrainEditField_2Label = uilabel(app.grainTab_2);
            app.lengthofgrainEditField_2Label.Text = 'length of grain';
            app.lengthofgrainEditField_2 = uieditfield(app.grainTab_2, 'numeric');
            app.savegrainButton_2 = uibutton(app.grainTab_2, 'push');
            app.savegrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @savegrainButton_2Pushed, true);
            app.savegrainButton_2.Text = 'save grain ';
            app.playtimeEditField_2Label = uilabel(app.grainTab_2);
            app.playtimeEditField_2Label.Text = 'playtime';
            app.playtimeEditField_2 = uieditfield(app.grainTab_2, 'numeric');
            app.UITable_2 = uitable(app.grainTab_2);
            app.UITable_2.ColumnName = {'grain name'};
            app.UITable_2.RowName = {};
            app.clearButton_2 = uibutton(app.grainTab_2, 'push');
            app.clearButton_2.Text = 'clear';
            app.setgrainButton_2 = uibutton(app.grainTab_2, 'push');
            app.setgrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @setgrainButton_2Pushed, true);
            app.setgrainButton_2.Text = 'set grain';
            app.playlistButton_2 = uibutton(app.grainTab_2, 'push');
            app.playlistButton_2.ButtonPushedFcn = createCallbackFcn(app, @playlistButton_2Pushed, true);
            app.playlistButton_2.Text = 'play list ';
            app.grainnameEditField_2Label = uilabel(app.grainTab_2);
            app.grainnameEditField_2Label.Text = 'grain name ';
            app.grainnameEditField_2 = uieditfield(app.grainTab_2, 'text');
            app.grainnameEditField_2.ValueChangedFcn = createCallbackFcn(app, @grainnameEditField_2ValueChanged, true);
            app.adsrTab_2 = uitab(app.TabGroup2_2);
            app.adsrTab_2.Title = 'adsr';
            app.attackSlider_2Label = uilabel(app.adsrTab_2);
            app.attackSlider_2Label.Text = 'attack ';
            app.attackSlider_2 = uislider(app.adsrTab_2);
            app.attackSlider_2.ValueChangedFcn = createCallbackFcn(app, @attackSlider_2ValueChanged, true);
            app.decaySlider_2Label = uilabel(app.adsrTab_2);
            app.decaySlider_2Label.Text = 'decay';
            app.decaySlider_2 = uislider(app.adsrTab_2);
            app.decaySlider_2.ValueChangedFcn = createCallbackFcn(app, @decaySlider_2ValueChanged, true);
            app.sustainSlider_2Label = uilabel(app.adsrTab_2);
            app.sustainSlider_2Label.Text = 'sustain';
            app.sustainSlider_2 = uislider(app.adsrTab_2);
            app.sustainSlider_2.ValueChangedFcn = createCallbackFcn(app, @sustainSlider_2ValueChanged, true);
            app.releseSlider_2Label = uilabel(app.adsrTab_2);
            app.releseSlider_2Label.Text = 'relese ';
            app.releseSlider_2 = uislider(app.adsrTab_2);
            app.releseSlider_2.ValueChangedFcn = createCallbackFcn(app, @releseSlider_2ValueChanged, true);
            app.Switch_2 = uiswitch(app.adsrTab_2, 'slider');
            app.changesTab_2 = uitab(app.TabGroup2_2);
            app.changesTab_2.Title = 'changes';
            app.tempoKnob_2Label = uilabel(app.changesTab_2);
            app.tempoKnob_2Label.Text = 'tempo';
            app.tempoKnob_2 = uiknob(app.changesTab_2, 'continuous');
            app.tempoKnob_2.Limits = [0 2];
            app.tempoKnob_2.MajorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_2.MajorTickLabels = {'0', '0.29', '0.58', '0.87', '1.16', '1.45', '1.74', '2'};
            app.tempoKnob_2.ValueChangedFcn = createCallbackFcn(app, @tempoKnob_2ValueChanged, true);
            app.tempoKnob_2.MinorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_2.Value = 1;
            app.PitchKnobLabel = uilabel(app.changesTab_2);
            app.PitchKnobLabel.Text = 'Pitch';
            app.PitchKnob_2 = uiknob(app.changesTab_2, 'continuous');
            app.PitchKnob_2.Limits = [0.1 2];
            app.PitchKnob_2.ValueChangedFcn = createCallbackFcn(app, @PitchKnob_2ValueChanged, true);
            app.PitchKnob_2.Value = 1.05;
            app.volumeSlider_2Label = uilabel(app.changesTab_2);
            app.volumeSlider_2Label.Text = 'volume';
            app.volumeSlider_2 = uislider(app.changesTab_2);
            app.volumeSlider_2.Limits = [0 2];
            app.volumeSlider_2.Value = 1;
            app.chordDropDown_2Label = uilabel(app.changesTab_2);
            app.chordDropDown_2Label.Text = 'chord';
            app.chordDropDown_2 = uidropdown(app.changesTab_2);
            app.chordDropDown_2.Items = {'F3', 'G3', 'A3', 'B3', 'C4', 'D4', 'C4', 'D4', 'E4', 'F4', 'G4', 'A4', 'B4', 'C5', 'D5'};
            app.chordDropDown_2.Value = 'F3';
            app.Switch_4 = uiswitch(app.changesTab_2, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.flippedaudioLabel_2 = uilabel(app.changesTab_2);
            app.flippedaudioLabel_2.Text = 'flipped audio ';
            app.HUMREMVALTab_2 = uitab(app.TabGroup2_2);
            app.HUMREMVALTab_2.Title = 'HUMREMVAL';
            app.cutButton_2 = uibutton(app.HUMREMVALTab_2, 'push');
            app.cutButton_2.ButtonPushedFcn = createCallbackFcn(app, @cutButton_2Pushed, true);
            app.cutButton_2.Text = 'cut ';
            app.selectButton_2 = uibutton(app.HUMREMVALTab_2, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButton_2Pushed, true);
            app.selectButton_2.Text = 'select';
            app.effectsTab_2 = uitab(app.TabGroup2_2);
            app.effectsTab_2.Title = 'effects';
            app.delay_switch_2 = uiswitch(app.effectsTab_2, 'slider');
            app.delay_switch_2.ValueChangedFcn = createCallbackFcn(app, @delay_switch_2ValueChanged, true);
            app.delayLabel_2 = uilabel(app.effectsTab_2);
            app.delayLabel_2.Text = 'delay';
            app.wahwahswitch_2 = uiswitch(app.effectsTab_2, 'slider');
            app.wahwahswitch_2.ValueChangedFcn = createCallbackFcn(app, @wahwahswitch_2ValueChanged, true);
            app.wahwahLabel_2 = uilabel(app.effectsTab_2);
            app.wahwahLabel_2.Text = 'wah wah ';
            app.resetButton_2 = uibutton(app.Audio2Tab, 'push');
            app.resetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetButton_2Pushed, true);
            app.resetButton_2.Text = 'reset';
            app.PlayButton_4 = uibutton(app.UIFigure, 'push');
            app.PlayButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_4Pushed, true);
            app.PlayButton_4.Text = 'Play';
            app.stopButton_4 = uibutton(app.UIFigure, 'push');
            app.stopButton_4.Text = 'stop';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.Text = 'save';
            app.convolesoundsButton = uibutton(app.UIFigure, 'push');
            app.convolesoundsButton.ButtonPushedFcn = createCallbackFcn(app, @convolesoundsButtonPushed, true);
            app.convolesoundsButton.Text = 'convole sounds ';
            app.tempoKnob_3Label = uilabel(app.UIFigure);
            app.tempoKnob_3Label.Text = 'tempo';
            app.tempoKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.tempoKnob_3.Limits = [0 2];
            app.tempoKnob_3.MajorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_3.MajorTickLabels = {'0', '0.29', '0.58', '0.87', '1.16', '1.45', '1.74', '2'};
            app.tempoKnob_3.MinorTicks = [0 0.29 0.58 0.87 1.16 1.45 1.74 2];
            app.tempoKnob_3.Value = 1;
            app.PitchKnob_3Label = uilabel(app.UIFigure);
            app.PitchKnob_3Label.Text = 'Pitch';
            app.PitchKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.PitchKnob_3.Limits = [0.1 2];
            app.PitchKnob_3.Value = 1.05;
            app.volumeSlider_3Label = uilabel(app.UIFigure);
            app.volumeSlider_3Label.Text = 'volume';
            app.volumeSlider_3 = uislider(app.UIFigure);
            app.volumeSlider_3.Limits = [0 2];
            app.volumeSlider_3.Value = 1;
            app.attackSlider_3Label = uilabel(app.UIFigure);
            app.attackSlider_3Label.Text = 'attack ';
            app.attackSlider_3 = uislider(app.UIFigure);
            app.attackSlider_3.ValueChangedFcn = createCallbackFcn(app, @attackSlider_3ValueChanged, true);
            app.decaySlider_4Label = uilabel(app.UIFigure);
            app.decaySlider_4Label.Text = 'decay';
            app.decaySlider_4 = uislider(app.UIFigure);
            app.decaySlider_4.ValueChangedFcn = createCallbackFcn(app, @decaySlider_4ValueChanged, true);
            app.sustainSlider_3Label = uilabel(app.UIFigure);
            app.sustainSlider_3Label.Text = 'sustain';
            app.sustainSlider_3 = uislider(app.UIFigure);
            app.sustainSlider_3.ValueChangedFcn = createCallbackFcn(app, @sustainSlider_3ValueChanged, true);
            app.releseSlider_3Label = uilabel(app.UIFigure);
            app.releseSlider_3Label.Text = 'relese ';
            app.releseSlider_3 = uislider(app.UIFigure);
            app.releseSlider_3.ValueChangedFcn = createCallbackFcn(app, @releseSlider_3ValueChanged, true);
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.adsrLabel = uilabel(app.UIFigure);
            app.adsrLabel.Text = 'adsr ';
            app.flippedaudioLabel_3 = uilabel(app.UIFigure);
            app.flippedaudioLabel_3.Text = 'flipped audio ';
            app.fliped_3 = uiswitch(app.UIFigure, 'slider');
            app.fliped_3.ValueChangedFcn = createCallbackFcn(app, @fliped_3ValueChanged, true);
            app.delay_switch_3 = uiswitch(app.UIFigure, 'slider');
            app.delay_switch_3.ValueChangedFcn = createCallbackFcn(app, @delay_switch_3ValueChanged, true);
            app.delayLabel_3 = uilabel(app.UIFigure);
            app.delayLabel_3.Text = 'delay';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.ContextMenu);
            app.Menu3.Text = 'Menu3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end